classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        DVisualButtonGroup   matlab.ui.container.ButtonGroup
        RGBButton            matlab.ui.control.RadioButton
        IRButton             matlab.ui.control.RadioButton
        AnalysisButtonGroup  matlab.ui.container.ButtonGroup
        OnButton             matlab.ui.control.RadioButton
        OffButton            matlab.ui.control.RadioButton
        GraphButton          matlab.ui.control.Button
        CalibrateButton_2    matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
   
% Read and store all images, also store flipped/reversed images
cdata = flipdim( imread('transformed_stitch.JPG'), 1 );
cdatar = flipdim( cdata, 2 );

cdata2 = flipdim( imread('Top.jpg'), 1 );
cdatar2 = flipdim( cdata2, 2 );

% for final code:
% cdata=south(front)
% cdata2=west(left)
% cdata3=east(right)
% cdata4=north(back)
% cdata5=top

% Pull size for the surfaces from front image for x and z.  Pull size from
% top image for y (depth)
[z1,x1]=size(cdata);
x1=x1/3;
y1=1000;
x1=x1;
y1=y1;
z1=z1;

% Create solid color for bottom surface (gray)
C=[0, 0, 0];
figure
% Create surfaces
% font (south)
surface([0 x1; 0 x1], [0 0; 0 0], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata );

% left
surface([0 0; 0 0], [0 y1; 0 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdatar );
% right
surface([x1 x1; x1 x1], [0 y1; 0 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata );
% back
surface([0 x1; 0 x1], [y1 y1; y1 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdatar );
% top
surface([0 x1; 0 x1], [0 0; y1 y1], [z1 z1; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata2 );
% bottom
surface([0 x1; 0 x1], [0 0; y1 y1], [0 0; 0 0], ...
    C);
%}
% set 3 dimensional view
view(3);

% set figure axis so it doesn't distort images
axis equal
%axis off
% pull up camera toolbar and set preferences so rotation is set by default
cameratoolbar('NoReset')
cameratoolbar('SetMode', 'orbit')


%{
% clear all
% close all
% clc

% Read and store all images, also store flipped/reversed images
cdata = flipdim( imread('transformed_stitch.JPG'), 1 );


% cdata(:,2)=uint8(randi(255, [length(cdata(:,2)) 1]))

cdatar = flipdim( cdata, 2 );

cdata2 = flipdim( imread('Top.jpg'), 1 );
cdatar2 = flipdim( cdata2, 2 );


% for final code:
% cdata=south(front)
% cdata2=west(left)
% cdata3=east(right)
% cdata4=north(back)
% cdata5=top

% Pull size for the surfaces from front image for x and z.  Pull size from
% top image for y (depth)
[z1,x1]=size(cdata);
x1=x1/3;
y1=1000;
x1=x1;
y1=y1;
z1=z1;

% Create solid color for bottom surface (gray)
C=[0, 0, 0];

% Create surfaces
% font (south)
ax=app.UIAxes


axes(app.UIAxes)
surf([0 x1; 0 x1], [0 0; 0 0], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata );


%{
% left
surf(ax, [0 0; 0 0], [0 y1; 0 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdatar );
% right
surf(ax, [x1 x1; x1 x1], [0 y1; 0 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata );
% back
surf(ax, [0 x1; 0 x1], [y1 y1; y1 y1], [0 0; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdatar );
% top
surf(ax, [0 x1; 0 x1], [0 0; y1 y1], [z1 z1; z1 z1], ...
    'FaceColor', 'texturemap', 'CData', cdata2 );
% bottom
surf(ax, [0 x1; 0 x1], [0 0; y1 y1], [0 0; 0 0], ...
    C);
%}

% set 3 dimensional view
%view(3);

% set figure axis so it doesn't distort images
axis equal
% axis off
% pull up camera toolbar and set preferences so rotation is set by default
% cameratoolbar('NoReset')
% cameratoolbar('SetMode', 'orbit')

% rotate3d on
% set(ax, 'Toolbar', 'figure')


%}


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.DVisualButtonGroup = uibuttongroup(app.UIFigure);
            app.DVisualButtonGroup.Title = '3D-Visual';
            app.RGBButton = uiradiobutton(app.DVisualButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.IRButton = uiradiobutton(app.DVisualButtonGroup);
            app.IRButton.Text = 'IR';
            app.AnalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.AnalysisButtonGroup.Title = 'Analysis';
            app.OnButton = uiradiobutton(app.AnalysisButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.AnalysisButtonGroup);
            app.OffButton.Text = 'Off';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.CalibrateButton_2 = uibutton(app.UIFigure, 'push');
            app.CalibrateButton_2.Text = 'Calibrate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end